classdef JMPTaskCreation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        BodiesTextAreaLabel        matlab.ui.control.Label
        JointsTextAreaLabel        matlab.ui.control.Label
        MarkersTextAreaLabel       matlab.ui.control.Label
        AddBodyButton_2            matlab.ui.control.Button
        EditBodyButton             matlab.ui.control.Button
        DeleteJointButton          matlab.ui.control.Button
        EditJointButton            matlab.ui.control.Button
        BodiesTextArea             matlab.ui.control.TextArea
        CloseButton                matlab.ui.control.Button
        JointsTextArea             matlab.ui.control.TextArea
        AddBodyButton              matlab.ui.control.Button
        AddJointButton             matlab.ui.control.Button
        MarkersTextArea            matlab.ui.control.TextArea
        EditMarkersButton          matlab.ui.control.Button
        EndTimeField               matlab.ui.control.NumericEditField
        ToLabel                    matlab.ui.control.Label
        StartTimeField             matlab.ui.control.NumericEditField
        TimeRangesEditFieldLabel   matlab.ui.control.Label
        OKButton                   matlab.ui.control.Button
        MarkerFileSearchButton     matlab.ui.control.Button
        MarkersFileEditField       matlab.ui.control.EditField
        MarkersFileEditFieldLabel  matlab.ui.control.Label
        EnabledCheckBox            matlab.ui.control.CheckBox
        TaskNameEditField          matlab.ui.control.EditField
        TaskNameEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        JMPBase;
        TaskApp JMPTaskCreation;
        JMPTask = struct();
        isEnabled logical = true;
        markerFileName string;
        selectedMarkers string;
        startTime double;
        endTime double;
        taskName string;
        JMPJoint cell;
        JMPJointSet struct = struct("JMPJointSet", "")
        JMPBody cell;
        JMPBodySet struct = struct("JMPBodySet", "")
    end
    
    methods (Access = public)
        
        function setSelectedObjects(app, objects)
            % Function to assign objects selected by ObjectSelectionWindow
            app.selectedMarkers = objects;
            app.onSelectedMarkersChanged();
        end

        function objects = getSelectedObjects(app)
            objects = app.selectedMarkers;
        end

        function jointSet = getJMPJointSet(app)
            jointSet = app.JMPJoint;
        end
        
        function addJoint(app, jointSet)
            app.JMPJoint{end+1} = jointSet;
        end

        function bodySet = getJMPBodySet(app)
            bodySet = app.JMPBody;
        end
        
        function addBody(app, bodySet)
            app.JMPBody{end+1} = bodySet;
        end

        function joints = getJMPModelJoints(app)
            joints = app.JMPBase.getJMPModelJoints();
        end

        function bodies = getJMPModelBodies(app)
            bodies = app.JMPBase.getJMPModelBodies();
        end
    end
    
    methods (Access = private)
        
        function onSelectedMarkersChanged(app)
            app.MarkersTextArea.Value = strjoin(app.selectedMarkers, ", ");
        end

        function parseMarkerFile(app)
            import org.opensim.modeling.TimeSeriesTableVec3
            if ~exist(app.markerFileName)
                return
            end
            timeSeriesTable = TimeSeriesTableVec3(app.markerFileName);
            numTimePoints = timeSeriesTable.getNumRows;
            timeVector = timeSeriesTable.getIndependentColumn;
            app.startTime = timeVector.get(0);
            app.endTime = timeVector.get(numTimePoints-1);
            app.StartTimeField.Value = double(app.startTime);
            app.EndTimeField.Value = double(app.endTime);
        end
        
        function constructJMPTask(app)
            if app.isEnabled
                app.JMPTask.is_enabled = 'true';
            else
                app.JMPTask.is_enabled = 'false';
            end
            app.JMPTask.Attributes.name = convertStringsToChars(app.taskName);
            app.JMPTask.index = numel(app.JMPBase.getJMPTaskList)+1;
            app.JMPTask.time_range = [app.startTime, app.endTime];
            app.JMPTask.marker_file_name = convertStringsToChars(app.markerFileName);
            app.JMPTask.marker_names = convertStringsToChars( ...
                strjoin(app.selectedMarkers, " "));
            app.JMPTask.JMPJointSet.JMPJoint = app.JMPJoint;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, JMPBase)
            app.JMPBase = JMPBase;
        end

        % Button pushed function: MarkerFileSearchButton
        function MarkerFileSearchButtonPushed(app, event)
        [file, path] = uigetfile('*.trc', "Select a trc marker file.");
        % User hit "Cancel"
        if isequal(file, 0)
            return
        end
  
        fullpath = fullfile(path, file);
    
        % Update the text field
        app.MarkersFileEditField.Value = fullpath;
        app.markerFileName = fullpath;

        parseMarkerFile(app);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.constructJMPTask();
            app.JMPBase.addJMPTask(app.JMPTask);

            delete(app.UIFigure);
        end

        % Button pushed function: EditMarkersButton
        function EditMarkersButtonPushed(app, event)
            modelMarkers = app.JMPBase.getJMPModelMarkers();
            ObjectSelectionWindow(app, modelMarkers, ...
                app.selectedMarkers);
        end

        % Button pushed function: AddJointButton
        function AddJointButtonPushed(app, event)
            JMPJointSelection(app, app.JMPBody);
        end

        % Button pushed function: AddBodyButton
        function AddBodyButtonPushed(app, event)

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app.UIFigure);
        end

        % Value changed function: MarkersTextArea
        function MarkersTextAreaValueChanged(app, event)
            parseMarkerFile(app);
        end

        % Value changed function: EnabledCheckBox
        function EnabledCheckBoxValueChanged(app, event)
            value = app.EnabledCheckBox.Value;
            app.isEnabled = value;
        end

        % Value changed function: TaskNameEditField
        function TaskNameEditFieldValueChanged(app, event)
            value = app.TaskNameEditField.Value;
            app.taskName = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TaskNameEditFieldLabel = uilabel(app.UIFigure);
            app.TaskNameEditFieldLabel.Text = 'Task Name';
            app.TaskNameEditField = uieditfield(app.UIFigure, 'text');
            app.TaskNameEditField.ValueChangedFcn = createCallbackFcn(app, @TaskNameEditFieldValueChanged, true);
            app.TaskNameEditField.Value = 'Default';
            app.EnabledCheckBox = uicheckbox(app.UIFigure);
            app.EnabledCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnabledCheckBoxValueChanged, true);
            app.EnabledCheckBox.Text = 'Enabled';
            app.EnabledCheckBox.Value = true;
            app.MarkersFileEditFieldLabel = uilabel(app.UIFigure);
            app.MarkersFileEditFieldLabel.Text = 'Markers File';
            app.MarkersFileEditField = uieditfield(app.UIFigure, 'text');
            app.MarkerFileSearchButton = uibutton(app.UIFigure, 'push');
            app.MarkerFileSearchButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerFileSearchButtonPushed, true);
            app.MarkerFileSearchButton.Icon = fullfile(pathToMLAPP, 'Images', 'folderIcon.svg');
            app.MarkerFileSearchButton.Text = '';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.TimeRangesEditFieldLabel = uilabel(app.UIFigure);
            app.TimeRangesEditFieldLabel.Text = 'Time Range (s):';
            app.StartTimeField = uieditfield(app.UIFigure, 'numeric');
            app.ToLabel = uilabel(app.UIFigure);
            app.ToLabel.Text = 'To:';
            app.EndTimeField = uieditfield(app.UIFigure, 'numeric');
            app.EditMarkersButton = uibutton(app.UIFigure, 'push');
            app.EditMarkersButton.ButtonPushedFcn = createCallbackFcn(app, @EditMarkersButtonPushed, true);
            app.EditMarkersButton.Text = 'Edit Markers';
            app.MarkersTextArea = uitextarea(app.UIFigure);
            app.MarkersTextArea.ValueChangedFcn = createCallbackFcn(app, @MarkersTextAreaValueChanged, true);
            app.MarkersTextArea.Editable = 'off';
            app.AddJointButton = uibutton(app.UIFigure, 'push');
            app.AddJointButton.ButtonPushedFcn = createCallbackFcn(app, @AddJointButtonPushed, true);
            app.AddJointButton.Text = 'Add Joint';
            app.AddBodyButton = uibutton(app.UIFigure, 'push');
            app.AddBodyButton.ButtonPushedFcn = createCallbackFcn(app, @AddBodyButtonPushed, true);
            app.AddBodyButton.Text = 'Add Body';
            app.JointsTextArea = uitextarea(app.UIFigure);
            app.JointsTextArea.Editable = 'off';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.BodiesTextArea = uitextarea(app.UIFigure);
            app.BodiesTextArea.Editable = 'off';
            app.EditJointButton = uibutton(app.UIFigure, 'push');
            app.EditJointButton.Text = 'Edit Joint';
            app.DeleteJointButton = uibutton(app.UIFigure, 'push');
            app.DeleteJointButton.Text = 'Delete Joint';
            app.EditBodyButton = uibutton(app.UIFigure, 'push');
            app.EditBodyButton.Text = 'Edit Body';
            app.AddBodyButton_2 = uibutton(app.UIFigure, 'push');
            app.AddBodyButton_2.Text = 'Add Body';
            app.MarkersTextAreaLabel = uilabel(app.UIFigure);
            app.MarkersTextAreaLabel.Text = 'Markers';
            app.JointsTextAreaLabel = uilabel(app.UIFigure);
            app.JointsTextAreaLabel.Text = 'Joints';
            app.BodiesTextAreaLabel = uilabel(app.UIFigure);
            app.BodiesTextAreaLabel.Text = 'Bodies';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JMPTaskCreation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end